classdef DataPreprocessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GestureTrainerUIFigure    matlab.ui.Figure
        DataPreprocessingLabel    matlab.ui.control.Label
        OverviewButton            matlab.ui.control.Button
        ProcessButton             matlab.ui.control.Button
        BasicsPanel               matlab.ui.container.Panel
        FileSelector              wt.FileSelector
        RawdatasetdirectoryLabel  matlab.ui.control.Label
        EditField_2               matlab.ui.control.EditField
        NameLabel                 matlab.ui.control.Label
        DatafeaturesPanel         matlab.ui.container.Panel
        RotationCheckBox          matlab.ui.control.CheckBox
        PositionCheckBox          matlab.ui.control.CheckBox
        DoFselectionLabel         matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
        JointselectionLabel       matlab.ui.control.Label
        ParticipantSplitPanel     matlab.ui.container.Panel
        ListBox_2                 matlab.ui.control.ListBox
        MethodLabel               matlab.ui.control.Label
        OtherparametersPanel      matlab.ui.container.Panel
        Spinner_3                 matlab.ui.control.Spinner
        Spinner_4Label            matlab.ui.control.Label
        Spinner_4                 matlab.ui.control.Spinner
        Label                     matlab.ui.control.Label
        Spinner_2                 matlab.ui.control.Spinner
        TrainValidationTestLabel  matlab.ui.control.Label
        SplitratioLabel           matlab.ui.control.Label
        Spinner                   matlab.ui.control.Spinner
        WindowsizeLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OverviewButton
        function OverviewButtonPushed(app, event)
            close(app.GestureTrainerUIFigure);
            Overview();
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            try
                % Retrieve inputs from the UI components
                dataset_name = app.EditField_2.Value;
                selected_joints = app.ListBox.Value;  % Selected joints from UI
                data_augmentation_methods = app.ListBox_2.Value; % Selected data augmentation methods
                train_ratio = app.Spinner_2.Value;  % Train ratio from UI
                val_ratio = app.Spinner_3.Value;  % Validation ratio from UI
                test_ratio_from_ui = app.Spinner_4.Value;  % Test ratio from UI (given by user)
                test_ratio_calculated = 10 - train_ratio - val_ratio;  % Calculated test ratio based on train/val

                % Validate ratio logic
                if test_ratio_calculated ~= test_ratio_from_ui
                    uialert(app.GestureTrainerUIFigure, 'Please correct the mismatched ratio. Check command window for details.', 'Error');
                    disp(['Mismatch: UI Test Ratio: ', num2str(test_ratio_from_ui), ' | Calculated Test Ratio: ', num2str(test_ratio_calculated)]);
                    return;
                end

                window_size = app.Spinner.Value;  % Window size from UI
                position = app.PositionCheckBox.Value;  % Position checkbox
                rotation = app.RotationCheckBox.Value;  % Rotation checkbox
                
                if position && ~rotation
                    mode = 0;
                elseif ~position && rotation
                    mode = 1;
                elseif position && rotation
                    mode = 2;
                end

                raw_data_dir = app.FileSelector.Value;
                data = SharedData.getInstance();  
                project_dir = data.project_dir;

                % Display a success message in the command window or in the app
                uialert(app.GestureTrainerUIFigure, 'Dataset preprocessing starts! Please check command window for details.', 'Success');

                % Call the external preprocessing function
                processDataset(selected_joints, data_augmentation_methods, train_ratio, val_ratio, window_size, mode, raw_data_dir, project_dir, dataset_name);
                
                % Display a success message in the command window or in the app
                uialert(app.GestureTrainerUIFigure, 'Dataset preprocessing completed!', 'Success');
            catch ME
                % Display an error message in the command window or in the app
                disp('An error occurred during dataset generation:');
                disp(ME.message);
                uialert(app.GestureTrainerUIFigure, 'An error occurred during dataset generation. Check command window for details.', 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GestureTrainerUIFigure = uifigure('Visible', 'off');
            app.GestureTrainerUIFigure.Name = 'GestureTrainer';
            app.OtherparametersPanel = uipanel(app.GestureTrainerUIFigure);
            app.OtherparametersPanel.Title = 'Other parameters';
            app.WindowsizeLabel = uilabel(app.OtherparametersPanel);
            app.WindowsizeLabel.Text = 'Window size';
            app.Spinner = uispinner(app.OtherparametersPanel);
            app.Spinner.Limits = [1 300];
            app.Spinner.Value = 60;
            app.SplitratioLabel = uilabel(app.OtherparametersPanel);
            app.SplitratioLabel.Text = 'Split ratio';
            app.TrainValidationTestLabel = uilabel(app.OtherparametersPanel);
            app.TrainValidationTestLabel.Text = 'Train : Validation : Test';
            app.Spinner_2 = uispinner(app.OtherparametersPanel);
            app.Spinner_2.Limits = [0 10];
            app.Spinner_2.Value = 6;
            app.Label = uilabel(app.OtherparametersPanel);
            app.Label.Text = ': ';
            app.Spinner_4 = uispinner(app.OtherparametersPanel);
            app.Spinner_4.Limits = [0 10];
            app.Spinner_4.Value = 2;
            app.Spinner_4Label = uilabel(app.OtherparametersPanel);
            app.Spinner_4Label.Text = ': ';
            app.Spinner_3 = uispinner(app.OtherparametersPanel);
            app.Spinner_3.Limits = [0 10];
            app.Spinner_3.Value = 2;
            app.ParticipantSplitPanel = uipanel(app.GestureTrainerUIFigure);
            app.ParticipantSplitPanel.Title = 'Participant Split';
            app.MethodLabel = uilabel(app.ParticipantSplitPanel);
            app.MethodLabel.Text = 'Method';
            app.ListBox_2 = uilistbox(app.ParticipantSplitPanel);
            app.ListBox_2.Items = {'Within Participant', 'Across Participant'};
            app.ListBox_2.Value = {};
            app.DatafeaturesPanel = uipanel(app.GestureTrainerUIFigure);
            app.DatafeaturesPanel.Title = 'Data features';
            app.JointselectionLabel = uilabel(app.DatafeaturesPanel);
            app.JointselectionLabel.Text = 'Joint selection';
            app.ListBox = uilistbox(app.DatafeaturesPanel);
            app.ListBox.Items = {'Whole hand (25)', 'Fingertips (5)', 'Palm (11)', 'Intermediate Phalanx (4)', 'Distal Phalanx (5)'};
            app.ListBox.Value = {};
            app.DoFselectionLabel = uilabel(app.DatafeaturesPanel);
            app.DoFselectionLabel.Text = 'DoF selection';
            app.PositionCheckBox = uicheckbox(app.DatafeaturesPanel);
            app.PositionCheckBox.Text = 'Position';
            app.RotationCheckBox = uicheckbox(app.DatafeaturesPanel);
            app.RotationCheckBox.Text = 'Rotation';
            app.BasicsPanel = uipanel(app.GestureTrainerUIFigure);
            app.BasicsPanel.Title = 'Basics';
            app.NameLabel = uilabel(app.BasicsPanel);
            app.NameLabel.Text = 'Name';
            app.EditField_2 = uieditfield(app.BasicsPanel, 'text');
            app.RawdatasetdirectoryLabel = uilabel(app.BasicsPanel);
            app.RawdatasetdirectoryLabel.Text = 'Raw dataset directory';
            app.FileSelector = wt.FileSelector(app.BasicsPanel);
            app.FileSelector.SelectionType = 'folder';
            app.ProcessButton = uibutton(app.GestureTrainerUIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Icon = fullfile(pathToMLAPP, 'Play.png');
            app.ProcessButton.Text = 'Process';
            app.OverviewButton = uibutton(app.GestureTrainerUIFigure, 'push');
            app.OverviewButton.ButtonPushedFcn = createCallbackFcn(app, @OverviewButtonPushed, true);
            app.OverviewButton.Icon = fullfile(pathToMLAPP, 'Overview.png');
            app.OverviewButton.Text = 'Overview';
            app.DataPreprocessingLabel = uilabel(app.GestureTrainerUIFigure);
            app.DataPreprocessingLabel.Text = 'Data Preprocessing';
            % Show the figure after all components are created
            app.GestureTrainerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataPreprocessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GestureTrainerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GestureTrainerUIFigure)
        end
    end
end