classdef ModelSpec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GestureTrainerUIFigure    matlab.ui.Figure
        ModelSpecificationLabel   matlab.ui.control.Label
        OverviewButton            matlab.ui.control.Button
        ConfirmButton             matlab.ui.control.Button
        BasicsPanel               matlab.ui.container.Panel
        EditField                 matlab.ui.control.EditField
        NameLabel                 matlab.ui.control.Label
        ModelconfigurationsPanel  matlab.ui.container.Panel
        Hyper_6EditField          matlab.ui.control.NumericEditField
        Hyper_6EditFieldLabel     matlab.ui.control.Label
        Hyper_5EditField          matlab.ui.control.NumericEditField
        Hyper_5EditFieldLabel     matlab.ui.control.Label
        Hyper_4EditField          matlab.ui.control.NumericEditField
        Hyper_4EditFieldLabel     matlab.ui.control.Label
        Hyper_3EditField          matlab.ui.control.NumericEditField
        Hyper_3EditFieldLabel     matlab.ui.control.Label
        Hyper_2EditField          matlab.ui.control.NumericEditField
        Hyper_2EditFieldLabel     matlab.ui.control.Label
        Hyper_1EditField          matlab.ui.control.NumericEditField
        Hyper_1EditFieldLabel     matlab.ui.control.Label
        HyperparametersLabel      matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
        ModelarchitectureLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OverviewButton
        function OverviewButtonPushed(app, event)
            close(app.GestureTrainerUIFigure);
            Overview();
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Get selected model architecture
            selectedModelArchitecture = app.ListBox.Value;
        
            hyperparameters.Hyper_1 = app.Hyper_1EditField.Value;
            hyperparameters.Hyper_2 = app.Hyper_2EditField.Value;
            hyperparameters.Hyper_3 = app.Hyper_3EditField.Value;
            hyperparameters.Hyper_4 = app.Hyper_4EditField.Value;
            hyperparameters.Hyper_5 = app.Hyper_5EditField.Value;
            hyperparameters.Hyper_6 = app.Hyper_6EditField.Value;

            % Get model name
            modelName = app.EditField.Value;
            
            % Define base output directory
            data = SharedData.getInstance();  
            project_dir = data.project_dir;   % Retrieve the project_dir
            
            % Define JSON file name and path
            jsonFileName = strcat('model_', modelName, '.json');
            json_file_path = fullfile(project_dir, 'model', jsonFileName);
            
            % Check if the directory exists, and if not, create it
            json_dir = fileparts(json_file_path);
            if ~exist(json_dir, 'dir')
                mkdir(json_dir);
            end
            
            % Create structure for JSON
            modelCustomisation.ModelName = modelName;
            modelCustomisation.ModelArchitecture = selectedModelArchitecture;
            modelCustomisation.Hyperparameters = hyperparameters;
            
            % Encode structure as JSON
            json_str = jsonencode(modelCustomisation);
            
            % Write the JSON string to the file
            fid = fopen(json_file_path, 'w');
            if fid == -1
                error('Cannot create JSON file: %s', json_file_path);
            end
            fwrite(fid, json_str, 'char');
            fclose(fid);
            
            fprintf('JSON file created successfully: %s\n', json_file_path);
        
            fprintf('Model Specification details saved to: %s\n', json_file_path);

            % Define the path to the project.json file
            project_description_dir = fullfile(project_dir, 'project.json');
            
            % Load the existing project information from the JSON file
            if isfile(project_description_dir)
                % Read the existing JSON file content
                fid = fopen(project_description_dir, 'r');
                rawJsonText = fread(fid, '*char')';
                fclose(fid);
                
                % Decode the JSON text into a MATLAB structure
                projectInfo = jsondecode(rawJsonText);
            else
                % If the file does not exist, initialize an empty structure
                projectInfo = struct();
            end

            % Ensure the DataPreprocessing field exists and is a structure
            if ~isfield(projectInfo, 'ModelSpecification') || ~isstruct(projectInfo.ModelSpecification)
                projectInfo.ModelSpecification = struct();
            end
            
            % Ensure the dataset_name field exists and is a cell array
            if ~isfield(projectInfo.ModelSpecification, 'model_name') || ~iscell(projectInfo.ModelSpecification.model_name)
                projectInfo.ModelSpecification.model_name = {};
            end
            
            % Define the new dataset name
            newModelName = modelName;
            
            % Check if the new dataset name already exists in the dataset_name list
            if ~ismember(newModelName, projectInfo.ModelSpecification.model_name)
                % If it doesn't exist, add it to the list
                projectInfo.ModelSpecification.model_name{end+1} = newModelName;
            end

            projectInfo.LastModifiedOn = datetime('now');
            
            % Encode the modified project information as JSON
            jsonText = jsonencode(projectInfo);
            
            % Write the modified JSON back to the file
            fid = fopen(project_description_dir, 'w');
            fwrite(fid, jsonText, 'char');
            fclose(fid);
            
            fprintf('Modified project JSON file\n');

            uialert(app.GestureTrainerUIFigure, 'Model Specification Done!', 'Success');
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            switch value
                case 'CNN'
                    app.Hyper_1EditFieldLabel.Text = 'numFilters_C1';
                    app.Hyper_1EditField.Value = 16;
                    app.Hyper_2EditFieldLabel.Text = 'numFilters_C2';
                    app.Hyper_2EditField.Value = 32;
                    app.Hyper_3EditFieldLabel.Text = 'dropOut';
                    app.Hyper_3EditField.Value = 0.3;
                    app.Hyper_4EditFieldLabel.Text = 'MaxEpochs';
                    app.Hyper_4EditField.Value = 9;
                    app.Hyper_5EditFieldLabel.Text = 'LearnRate';
                    app.Hyper_5EditField.Value = 0.000009;
                    app.Hyper_6EditFieldLabel.Text = 'MiniBatchSize';
                    app.Hyper_6EditField.Value = 16;
                case 'LSTM'
                    app.Hyper_1EditFieldLabel.Text = 'numHiddenUnits_LSTM';
                    app.Hyper_1EditField.Value = 100;
                    app.Hyper_2EditFieldLabel.Text = 'numHiddenUnits_FC';
                    app.Hyper_2EditField.Value = 50;
                    app.Hyper_3EditFieldLabel.Text = 'dropOut';
                    app.Hyper_3EditField.Value = 0.3;
                    app.Hyper_4EditFieldLabel.Text = 'MaxEpochs';
                    app.Hyper_4EditField.Value = 27;
                    app.Hyper_5EditFieldLabel.Text = 'LearnRate';
                    app.Hyper_5EditField.Value = 0.00009;
                    app.Hyper_6EditFieldLabel.Text = 'MiniBatchSize';
                    app.Hyper_6EditField.Value = 16;
                case 'Transformer'
                    app.Hyper_1EditFieldLabel.Text = 'numHeads';
                    app.Hyper_1EditField.Value = 8;
                    app.Hyper_2EditFieldLabel.Text = 'numHiddenUnits';
                    app.Hyper_2EditField.Value = 256;
                    app.Hyper_3EditFieldLabel.Text = 'dropOut';
                    app.Hyper_3EditField.Value = 0.3;
                    app.Hyper_4EditFieldLabel.Text = 'MaxEpochs';
                    app.Hyper_4EditField.Value = 18;
                    app.Hyper_5EditFieldLabel.Text = 'LearnRate';
                    app.Hyper_5EditField.Value = 0.00009;
                    app.Hyper_6EditFieldLabel.Text = 'MiniBatchSize';
                    app.Hyper_6EditField.Value = 16;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GestureTrainerUIFigure = uifigure('Visible', 'off');
            app.GestureTrainerUIFigure.Name = 'GestureTrainer';
            app.ModelconfigurationsPanel = uipanel(app.GestureTrainerUIFigure);
            app.ModelconfigurationsPanel.Title = 'Model configurations';
            app.ModelarchitectureLabel = uilabel(app.ModelconfigurationsPanel);
            app.ModelarchitectureLabel.Text = 'Model architecture';
            app.ListBox = uilistbox(app.ModelconfigurationsPanel);
            app.ListBox.Items = {'CNN', 'LSTM', 'Transformer'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.HyperparametersLabel = uilabel(app.ModelconfigurationsPanel);
            app.HyperparametersLabel.Text = 'Hyper-parameters';
            app.Hyper_1EditFieldLabel = uilabel(app.ModelconfigurationsPanel);
            app.Hyper_1EditFieldLabel.Text = 'Hyper_1';
            app.Hyper_1EditField = uieditfield(app.ModelconfigurationsPanel, 'numeric');
            app.Hyper_1EditField.Limits = [0 256];
            app.Hyper_2EditFieldLabel = uilabel(app.ModelconfigurationsPanel);
            app.Hyper_2EditFieldLabel.Text = 'Hyper_2';
            app.Hyper_2EditField = uieditfield(app.ModelconfigurationsPanel, 'numeric');
            app.Hyper_2EditField.Limits = [0 256];
            app.Hyper_3EditFieldLabel = uilabel(app.ModelconfigurationsPanel);
            app.Hyper_3EditFieldLabel.Text = 'Hyper_3';
            app.Hyper_3EditField = uieditfield(app.ModelconfigurationsPanel, 'numeric');
            app.Hyper_3EditField.Limits = [0 1];
            app.Hyper_4EditFieldLabel = uilabel(app.ModelconfigurationsPanel);
            app.Hyper_4EditFieldLabel.Text = 'Hyper_4';
            app.Hyper_4EditField = uieditfield(app.ModelconfigurationsPanel, 'numeric');
            app.Hyper_4EditField.Limits = [0 100];
            app.Hyper_5EditFieldLabel = uilabel(app.ModelconfigurationsPanel);
            app.Hyper_5EditFieldLabel.Text = 'Hyper_5';
            app.Hyper_5EditField = uieditfield(app.ModelconfigurationsPanel, 'numeric');
            app.Hyper_5EditField.Limits = [0 0.1];
            app.Hyper_6EditFieldLabel = uilabel(app.ModelconfigurationsPanel);
            app.Hyper_6EditFieldLabel.Text = 'Hyper_6';
            app.Hyper_6EditField = uieditfield(app.ModelconfigurationsPanel, 'numeric');
            app.Hyper_6EditField.Limits = [0 256];
            app.BasicsPanel = uipanel(app.GestureTrainerUIFigure);
            app.BasicsPanel.Title = 'Basics';
            app.NameLabel = uilabel(app.BasicsPanel);
            app.NameLabel.Text = 'Name';
            app.EditField = uieditfield(app.BasicsPanel, 'text');
            app.ConfirmButton = uibutton(app.GestureTrainerUIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Icon = fullfile(pathToMLAPP, 'Confirm.png');
            app.ConfirmButton.Text = 'Confirm';
            app.OverviewButton = uibutton(app.GestureTrainerUIFigure, 'push');
            app.OverviewButton.ButtonPushedFcn = createCallbackFcn(app, @OverviewButtonPushed, true);
            app.OverviewButton.Icon = fullfile(pathToMLAPP, 'Overview.png');
            app.OverviewButton.Text = 'Overview';
            app.ModelSpecificationLabel = uilabel(app.GestureTrainerUIFigure);
            app.ModelSpecificationLabel.Text = 'Model Specification';
            % Show the figure after all components are created
            app.GestureTrainerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSpec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GestureTrainerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GestureTrainerUIFigure)
        end
    end
end