classdef Overview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GestureTrainerUIFigure          matlab.ui.Figure
        RefreshButton                   matlab.ui.control.Button
        OverviewLabel                   matlab.ui.control.Label
        TrainButton                     matlab.ui.control.Button
        DeployButton                    matlab.ui.control.Button
        DatapreprocessingPanel          matlab.ui.container.Panel
        DataSelectButton                matlab.ui.control.Button
        DataViewButton                  matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        ListBox_1                       matlab.ui.control.ListBox
        ListofpreprocesseddatasetLabel  matlab.ui.control.Label
        SelecteddatasetLabel_1          matlab.ui.control.Label
        ActionLabel_1                   matlab.ui.control.Label
        ModelspecificationPanel         matlab.ui.container.Panel
        ModelSelectButton               matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        ListBox_2                       matlab.ui.control.ListBox
        ModelViewButton                 matlab.ui.control.Button
        ActionLabel_2                   matlab.ui.control.Label
        SelectedmodelLabel              matlab.ui.control.Label
        ListofavailablemodelLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define the path to the project JSON file
            data = SharedData.getInstance();  
            project_dir = data.project_dir;   % Retrieve the project_dir
            jsonFilePath = fullfile(project_dir, 'project.json');
            
            % Check if the project.json file exists
            if isfile(jsonFilePath)
                % Read the project.json file
                jsonData = fileread(jsonFilePath);
                projectInfo = jsondecode(jsonData);
                
                % Process DataPreprocessing field
                if isfield(projectInfo, 'DataPreprocessing') && isfield(projectInfo.DataPreprocessing, 'dataset_name')
                    % Extract the dataset names directly
                    datasetNames = projectInfo.DataPreprocessing.dataset_name;
                    
                    % Ensure datasetNames is a cell array
                    if ischar(datasetNames)
                        datasetNames = {datasetNames}; % Convert single string to cell array
                    elseif isstring(datasetNames)
                        datasetNames = cellstr(datasetNames); % Convert string array to cell array
                    end
                    
                    % Update the ListBox with dataset names
                    app.ListBox_1.Items = datasetNames;
                else
                    % If the field does not exist or is empty, set an empty list
                    app.ListBox_1.Items = {};
                end
            
                % Process ModelSpecification field
                if isfield(projectInfo, 'ModelSpecification') && isfield(projectInfo.ModelSpecification, 'model_name')
                    % Extract the model names directly
                    modelNames = projectInfo.ModelSpecification.model_name;
                    
                    % Ensure modelNames is a cell array
                    if ischar(modelNames)
                        modelNames = {modelNames}; % Convert single string to cell array
                    elseif isstring(modelNames)
                        modelNames = cellstr(modelNames); % Convert string array to cell array
                    end
                    
                    % Update the ListBox with model names
                    app.ListBox_2.Items = modelNames;
                else
                    % If the field does not exist or is empty, set an empty list
                    app.ListBox_2.Items = {};
                end
                
                % Process Train Configuration
                if isfield(projectInfo, 'Train') && isfield(projectInfo.Train, 'configuration')
                    configInfo = projectInfo.Train.configuration;
                    
                    % Process DatasetPreprocess configuration
                    if isfield(configInfo, 'DatasetPreprocess') && isfield(configInfo.DatasetPreprocess, 'dataset_name')
                        % Update the EditField with the dataset name
                        app.EditField.Value = configInfo.DatasetPreprocess.dataset_name;
                    else
                        % If the field does not exist, set EditField to empty
                        app.EditField.Value = '';
                    end
                    
                    % Process ModelSpecification configuration
                    if isfield(configInfo, 'ModelSpecification') && isfield(configInfo.ModelSpecification, 'ModelName')
                        % Update the EditField with the model name
                        app.EditField_2.Value = configInfo.ModelSpecification.ModelName;
                    else
                        % If the field does not exist, set EditField to empty
                        app.EditField_2.Value = '';
                    end
                else
                    % If the Train configuration does not exist, set EditFields to empty
                    app.EditField.Value = '';
                    app.EditField_2.Value = '';
                end
                
            else
                % If the project.json file does not exist, set default messages
                app.ListBox_1.Items = {'project file does not exist.'};
                app.ListBox_2.Items = {'project file does not exist.'};
                app.EditField.Value = '';
                app.EditField_2.Value = '';
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            if ~isempty(app.EditField.Value) && ~isempty(app.EditField_2.Value)
                close(app.GestureTrainerUIFigure);
                Train();
            else
                uialert(app.GestureTrainerUIFigure, 'Please select dataset and model before training.', 'Error');
            end    
        end

        % Button pushed function: DeployButton
        function DeployButtonPushed(app, event)
            data = SharedData.getInstance(); 
            project_dir = data.project_dir;
            projectFile = fullfile(project_dir, 'project.json');
           
            % Read the project.json file content before modifying it
            fid = fopen(projectFile, 'r');
            rawJsonText = fread(fid, '*char')';

            % Decode the JSON text into a MATLAB structure
            projectInfo = jsondecode(rawJsonText);
            fclose(fid);
            
            if ~isempty(projectInfo.Deploy.trained_model) || ~isempty(projectInfo.Deploy.onnx)
                close(app.GestureTrainerUIFigure);
                Deploy();
            else
                % Show an error message if both fields are empty
                uialert(app.GestureTrainerUIFigure, 'Please train model before deploying.', 'Error');
            end 
        end

        % Button pushed function: DataSelectButton
        function DataSelectButtonPushed(app, event)
            if app.ListBox_1.Value == "Preprocess new dataset"
                close(app.GestureTrainerUIFigure);
                DataPreprocessing();
            else
                app.EditField.Value = app.ListBox_1.Value;
                try
                    data = SharedData.getInstance(); 
                    project_dir = data.project_dir;
                    projectFile = fullfile(project_dir, 'project.json');
                    dataset_dir = fullfile(project_dir, "dataset", app.EditField.Value, "dataset_customisation.json");
        
                    if isfile(dataset_dir)
                        % Read the existing JSON file content
                        fid = fopen(dataset_dir, 'r');
                        rawJsonText = fread(fid, '*char')';
                        fclose(fid);
                        % Decode the JSON text into a MATLAB structure
                        dataInfo = jsondecode(rawJsonText);
                    else
                        % If the file does not exist, initialize an empty structure
                        dataInfo = struct();
                    end
        
                    % Read the project.json file content before modifying it
                    fid = fopen(projectFile, 'r');
                    rawJsonText = fread(fid, '*char')';
                    fclose(fid);
        
                    % Decode the JSON text into a MATLAB structure
                    projectInfo = jsondecode(rawJsonText);
                    
                    % Ensure Train and configuration fields exist and are structures
                    if ~isfield(projectInfo, 'Train') || ~isstruct(projectInfo.Train)
                        projectInfo.Train = struct();
                    end
                    if ~isfield(projectInfo.Train, 'configuration') || ~isstruct(projectInfo.Train.configuration)
                        projectInfo.Train.configuration = struct();
                    end
                    
                    % Modify the field without changing other fields
                    projectInfo.Train.configuration.DatasetPreprocess = dataInfo;
                    projectInfo.LastModifiedOn = datetime('now');
                    
                    % Encode the modified project information as JSON
                    jsonText = jsonencode(projectInfo);
                    
                    % Write the modified JSON back to the project file
                    fid = fopen(projectFile, 'w');
                    fwrite(fid, jsonText, 'char');
                    fclose(fid);
                    
                    fprintf('Modified project JSON file\n');
                    
                    % Notify the user of successful save
                    uialert(app.GestureTrainerUIFigure, sprintf('Configuration for dataset saved successfully in:\n%s', projectFile), 'Save Success'); 
                catch ME
                    % Error handling
                    uialert(app.GestureTrainerUIFigure, sprintf('Error saving JSON file: %s', ME.message), 'Save Error');
                end
            end
        end

        % Button pushed function: ModelSelectButton
        function ModelSelectButtonPushed(app, event)
            if app.ListBox_2.Value == "Specify new model"
                close(app.GestureTrainerUIFigure);
                ModelSpec();
            else
                app.EditField_2.Value = app.ListBox_2.Value;
                try
                    data = SharedData.getInstance(); 
                    project_dir = data.project_dir;
                    projectFile = fullfile(project_dir, 'project.json');
                    model_file = strcat("model_", app.EditField_2.Value, ".json");
                    model_dir = fullfile(project_dir, "model", model_file);
        
                    if isfile(model_dir)
                        % Read the existing JSON file content
                        fid = fopen(model_dir, 'r');
                        rawJsonText = fread(fid, '*char')';
                        fclose(fid);
                        % Decode the JSON text into a MATLAB structure
                        modelInfo = jsondecode(rawJsonText);
                    else
                        % If the file does not exist, initialize an empty structure
                        modelInfo = struct();
                    end
        
                    % Read the project.json file content before modifying it
                    fid = fopen(projectFile, 'r');
                    rawJsonText = fread(fid, '*char')';
                    fclose(fid);
        
                    % Decode the JSON text into a MATLAB structure
                    projectInfo = jsondecode(rawJsonText);
                    
                    % Ensure Train and configuration fields exist and are structures
                    if ~isfield(projectInfo, 'Train') || ~isstruct(projectInfo.Train)
                        projectInfo.Train = struct();
                    end
                    if ~isfield(projectInfo.Train, 'configuration') || ~isstruct(projectInfo.Train.configuration)
                        projectInfo.Train.configuration = struct();
                    end
                    
                    % Modify the field without changing other fields
                    projectInfo.Train.configuration.ModelSpecification = modelInfo;
                    projectInfo.LastModifiedOn = datetime('now');
                    
                    % Encode the modified project information as JSON
                    jsonText = jsonencode(projectInfo); 
                    
                    % Write the modified JSON back to the project file
                    fid = fopen(projectFile, 'w');
                    fwrite(fid, jsonText, 'char');
                    fclose(fid);
                    
                    fprintf('Modified project JSON file\n');
                    
                    % Notify the user of successful save
                    uialert(app.GestureTrainerUIFigure, sprintf('Configuration for model saved successfully in:\n%s', projectFile), 'Save Success'); 
                catch ME
                    % Error handling
                    uialert(app.GestureTrainerUIFigure, sprintf('Error saving JSON file: %s', ME.message), 'Save Error');
                end
            end
        end

        % Button pushed function: DataViewButton
        function DataViewButtonPushed(app, event)
            try
                % Get shared data and project directory
                data = SharedData.getInstance(); 
                project_dir = data.project_dir;
                dataset_name = app.ListBox_1.Value;
                dataset_dir = fullfile(project_dir, "dataset", dataset_name, "dataset_customisation.json");
        
                % Check if the file exists
                if isfile(dataset_dir)
                    % Read the existing JSON file content
                    fid = fopen(dataset_dir, 'r');
                    rawJsonText = fread(fid, '*char')';
                    fclose(fid);
                    
                    % Decode the JSON text into a MATLAB structure
                    jsonData = jsondecode(rawJsonText);
        
                    % Convert the structure to a readable string format for display
                    jsonStr = jsonencode(jsonData, 'PrettyPrint', true);
        
                    % Display the content in a pop-up window (using a non-blocking dialog)
                    msgbox(jsonStr, 'Dataset Preprocessing Information', 'modal');
                else
                    % Show an error dialog if the file does not exist
                    uialert(app.GestureTrainerUIFigure, sprintf('Dataset Preprocessing file not found:\n%s', dataset_dir), 'File Not Found');
                end
            catch ME
                % Handle any errors that occur during file read or decoding
                uialert(app.GestureTrainerUIFigure, sprintf('Error reading the JSON file: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: ModelViewButton
        function ModelViewButtonPushed(app, event)
            try
                % Get shared data and project directory
                data = SharedData.getInstance(); 
                project_dir = data.project_dir;
                model_name = app.ListBox_2.Value;
                model_file = strcat("model_", model_name, ".json");
                model_dir = fullfile(project_dir, "model", model_file);
        
                % Check if the file exists
                if isfile(model_dir)
                    % Read the existing JSON file content
                    fid = fopen(model_dir, 'r');
                    rawJsonText = fread(fid, '*char')';
                    fclose(fid);
                    
                    % Decode the JSON text into a MATLAB structure
                    jsonData = jsondecode(rawJsonText);
        
                    % Convert the structure to a readable string format for display
                    jsonStr = jsonencode(jsonData, 'PrettyPrint', true);
        
                    % Display the content in a pop-up window (using a non-blocking dialog)
                    msgbox(jsonStr, 'Model Specification Information', 'modal');
                else
                    % Show an error dialog if the file does not exist
                    uialert(app.GestureTrainerUIFigure, sprintf('Model Specification file not found:\n%s', dataset_dir), 'File Not Found');
                end
            catch ME
                % Handle any errors that occur during file read or decoding
                uialert(app.GestureTrainerUIFigure, sprintf('Error reading the JSON file: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GestureTrainerUIFigure = uifigure('Visible', 'off');
            app.GestureTrainerUIFigure.Name = 'GestureTrainer';
            app.ModelspecificationPanel = uipanel(app.GestureTrainerUIFigure);
            app.ModelspecificationPanel.Title = 'Model specification';
            app.ListofavailablemodelLabel = uilabel(app.ModelspecificationPanel);
            app.ListofavailablemodelLabel.Text = 'List of available model:';
            app.SelectedmodelLabel = uilabel(app.ModelspecificationPanel);
            app.SelectedmodelLabel.Text = 'Selected model:';
            app.ActionLabel_2 = uilabel(app.ModelspecificationPanel);
            app.ActionLabel_2.Text = 'Action:';
            app.ModelViewButton = uibutton(app.ModelspecificationPanel, 'push');
            app.ModelViewButton.ButtonPushedFcn = createCallbackFcn(app, @ModelViewButtonPushed, true);
            app.ModelViewButton.Text = 'View';
            app.ListBox_2 = uilistbox(app.ModelspecificationPanel);
            app.ListBox_2.Items = {'', ''};
            app.ListBox_2.Value = {};
            app.EditField_2 = uieditfield(app.ModelspecificationPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.ModelSelectButton = uibutton(app.ModelspecificationPanel, 'push');
            app.ModelSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ModelSelectButtonPushed, true);
            app.ModelSelectButton.Text = 'Select';
            app.DatapreprocessingPanel = uipanel(app.GestureTrainerUIFigure);
            app.DatapreprocessingPanel.Title = 'Data preprocessing';
            app.ActionLabel_1 = uilabel(app.DatapreprocessingPanel);
            app.ActionLabel_1.Text = 'Action:';
            app.SelecteddatasetLabel_1 = uilabel(app.DatapreprocessingPanel);
            app.SelecteddatasetLabel_1.Text = 'Selected dataset:';
            app.ListofpreprocesseddatasetLabel = uilabel(app.DatapreprocessingPanel);
            app.ListofpreprocesseddatasetLabel.Text = 'List of preprocessed dataset:';
            app.ListBox_1 = uilistbox(app.DatapreprocessingPanel);
            app.ListBox_1.Items = {''};
            app.ListBox_1.Value = {};
            app.EditField = uieditfield(app.DatapreprocessingPanel, 'text');
            app.EditField.Editable = 'off';
            app.DataViewButton = uibutton(app.DatapreprocessingPanel, 'push');
            app.DataViewButton.ButtonPushedFcn = createCallbackFcn(app, @DataViewButtonPushed, true);
            app.DataViewButton.Text = 'View';
            app.DataSelectButton = uibutton(app.DatapreprocessingPanel, 'push');
            app.DataSelectButton.ButtonPushedFcn = createCallbackFcn(app, @DataSelectButtonPushed, true);
            app.DataSelectButton.Text = 'Select';
            app.DeployButton = uibutton(app.GestureTrainerUIFigure, 'push');
            app.DeployButton.ButtonPushedFcn = createCallbackFcn(app, @DeployButtonPushed, true);
            app.DeployButton.Icon = fullfile(pathToMLAPP, 'MR deploy.png');
            app.DeployButton.Text = 'Deploy';
            app.TrainButton = uibutton(app.GestureTrainerUIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Icon = fullfile(pathToMLAPP, 'Neural Network.png');
            app.TrainButton.Text = 'Train';
            app.OverviewLabel = uilabel(app.GestureTrainerUIFigure);
            app.OverviewLabel.Text = 'Overview';
            app.RefreshButton = uibutton(app.GestureTrainerUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'Refresh.png');
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.GestureTrainerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Overview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GestureTrainerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GestureTrainerUIFigure)
        end
    end
end