classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GestureTrainerUIFigure     matlab.ui.Figure
        ProjectLabel               matlab.ui.control.Label
        FileSelector               wt.FileSelector
        ProjectdirectoryLabel      matlab.ui.control.Label
        CreateLoadButton           matlab.ui.control.Button
        ProjectnameEditField       matlab.ui.control.EditField
        ProjectnameEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateLoadButton
        function CreateLoadButtonPushed(app, event)
            % Get the project directory and project name from the UI components
            projectDir = app.FileSelector.Value;
            projectName = app.ProjectnameEditField.Value;

            projectFolderPath = fullfile(projectDir, projectName);
    
            % Check if the folder already exists; if not, create it
            if ~exist(projectFolderPath, 'dir')
                mkdir(projectFolderPath);
                fprintf('Created project folder: %s\n', projectFolderPath);
            else
                fprintf('Project folder already exists: %s\n', projectFolderPath);
            end
            
            % Path for the JSON file
            projectFilePath = fullfile(projectFolderPath, 'project.json');
            
            % Check if the project.json file already exists
            if ~exist(projectFilePath, 'file')
                % Define project information
                projectInfo = struct(...
                    'ProjectName', projectName, ...
                    'ProjectDirectory', projectDir, ...
                    'DataPreprocessing', struct(...
                        'dataset_name', {{'Preprocess new dataset'}} ...
                    ), ...
                    'ModelSpecification', struct(...
                        'model_name', {{'Specify new model'}} ...
                    ), ...
                    'Train', struct(...
                        'configuration', struct() ...  % Initialize as an empty structure
                    ), ...
                    'Deploy', struct(...
                        'trained_model', {{}}, ...  % Initialize as an empty cell array
                        'onnx', {{}} ...            % Initialize as an empty cell array
                    ), ...
                    'LastModifiedOn', datetime('now') ...
                );
                
                % Encode project information as JSON
                jsonText = jsonencode(projectInfo);
                
                % Create and write to the JSON file
                fid = fopen(projectFilePath, 'w');
                fwrite(fid, jsonText, 'char');
                fclose(fid);
                
                fprintf('Created project JSON file: %s\n', projectFilePath);
            else
                fprintf('Project JSON file already exists: %s\n', projectFilePath);
            end

            data = SharedData.getInstance();
            data.project_dir = projectFolderPath;
            close(app.GestureTrainerUIFigure);
            Overview();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GestureTrainerUIFigure = uifigure('Visible', 'off');
            app.GestureTrainerUIFigure.Name = 'GestureTrainer';
            app.ProjectnameEditFieldLabel = uilabel(app.GestureTrainerUIFigure);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectnameEditField = uieditfield(app.GestureTrainerUIFigure, 'text');
            app.CreateLoadButton = uibutton(app.GestureTrainerUIFigure, 'push');
            app.CreateLoadButton.ButtonPushedFcn = createCallbackFcn(app, @CreateLoadButtonPushed, true);
            app.CreateLoadButton.Text = 'Create/Load';
            app.ProjectdirectoryLabel = uilabel(app.GestureTrainerUIFigure);
            app.ProjectdirectoryLabel.Text = 'Project directory';
            app.FileSelector = wt.FileSelector(app.GestureTrainerUIFigure);
            app.FileSelector.SelectionType = 'folder';
            app.ProjectLabel = uilabel(app.GestureTrainerUIFigure);
            app.ProjectLabel.Text = 'Project';
            % Show the figure after all components are created
            app.GestureTrainerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GestureTrainerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GestureTrainerUIFigure)
        end
    end
end