classdef Train < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GestureTrainerUIFigure    matlab.ui.Figure
        TrainLabel                matlab.ui.control.Label
        OverviewButton            matlab.ui.control.Button
        DeployButton              matlab.ui.control.Button
        TrainPanel                matlab.ui.container.Panel
        VerboseLabel              matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        AbandonButton             matlab.ui.control.Button
        TrainButton               matlab.ui.control.Button
        ActionLabel               matlab.ui.control.Label
        PretrainPanel             matlab.ui.container.Panel
        ViewButton_2              matlab.ui.control.Button
        ViewButton                matlab.ui.control.Button
        EditField_2               matlab.ui.control.EditField
        EditField                 matlab.ui.control.EditField
        SpecifiedmodelLabel       matlab.ui.control.Label
        PreprocesseddatasetLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function stop = customOutputFcn(app, info)
            stop = false;  % No manual stopping, handled by the training plot
            minibatch_freq = 50;
            val_freq = 30;
        
            % Print mini-batch statistics every 50 iterations
            if ~isempty(info.TrainingLoss) && mod(info.Iteration, minibatch_freq) == 0
                % Prepare and display mini-batch message
                miniBatchMsg = sprintf('Epoch: %d, Iteration: %d, Mini-batch Loss: %.4f, Mini-batch Accuracy: %.2f%%', ...
                                       info.Epoch, info.Iteration, info.TrainingLoss, info.TrainingAccuracy);
        
                % Append the mini-batch message to the VerboseTextArea
                app.TextArea.Value = [app.TextArea.Value; miniBatchMsg];
        
                % Ensure the latest message is visible by scrolling down
                drawnow;
            end
        
            % Print both mini-batch and validation statistics every 30 iterations
            if ~isempty(info.TrainingLoss) && ~isempty(info.ValidationLoss) && mod(info.Iteration, val_freq) == 0
                % Prepare and display combined mini-batch and validation message
                combinedMsg = sprintf(['Epoch: %d, Iteration: %d, Mini-batch Loss: %.4f, Mini-batch Accuracy: %.2f%%, ', ...
                                       'Validation Loss: %.4f, Validation Accuracy: %.2f%%'], ...
                                       info.Epoch, info.Iteration, info.TrainingLoss, info.TrainingAccuracy, ...
                                       info.ValidationLoss, info.ValidationAccuracy);
        
                % Append the combined message to the VerboseTextArea
                app.TextArea.Value = [app.TextArea.Value; combinedMsg];
        
                % Ensure the latest message is visible by scrolling down
                drawnow;
            end
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define the path to your project JSON file
            data = SharedData.getInstance();  
            project_dir = data.project_dir;   % Retrieve the project_dir
            jsonFilePath = fullfile(project_dir, 'project.json');
            
            % Check if the project.json file exists
            if isfile(jsonFilePath)
                % Read the project.json file
                jsonData = fileread(jsonFilePath);
                projectInfo = jsondecode(jsonData);
               
                % Process Train Configuration
                if isfield(projectInfo, 'Train') && isfield(projectInfo.Train, 'configuration')
                    configInfo = projectInfo.Train.configuration;
                    
                    % Process DatasetPreprocess configuration
                    if isfield(configInfo, 'DatasetPreprocess') && isfield(configInfo.DatasetPreprocess, 'dataset_name')
                        % Update the EditField with the dataset name
                        app.EditField.Value = configInfo.DatasetPreprocess.dataset_name;
                    else
                        % If the field does not exist, set EditField to empty
                        app.EditField.Value = '';
                    end
                    
                    % Process ModelSpecification configuration
                    if isfield(configInfo, 'ModelSpecification') && isfield(configInfo.ModelSpecification, 'ModelName')
                        % Update the EditField with the model name
                        app.EditField_2.Value = configInfo.ModelSpecification.ModelName;
                    else
                        % If the field does not exist, set EditField to empty
                        app.EditField_2.Value = '';
                    end
                else
                    % If the Train configuration does not exist, set EditFields to empty
                    app.EditField.Value = '';
                    app.EditField_2.Value = '';
                end
                
            else
                % If the project.json file does not exist, set default messages
                app.EditField.Value = '';
                app.EditField_2.Value = '';
            end
        end

        % Button pushed function: OverviewButton
        function OverviewButtonPushed(app, event)
            close(app.GestureTrainerUIFigure);
            Overview();
        end

        % Button pushed function: DeployButton
        function DeployButtonPushed(app, event)
            data = SharedData.getInstance(); 
            project_dir = data.project_dir;
            projectFile = fullfile(project_dir, 'project.json');
           
            % Read the project.json file content before modifying it
            fid = fopen(projectFile, 'r');
            rawJsonText = fread(fid, '*char')';

            % Decode the JSON text into a MATLAB structure
            projectInfo = jsondecode(rawJsonText);
            fclose(fid);
            
            if ~isempty(projectInfo.Deploy.trained_model) || ~isempty(projectInfo.Deploy.onnx)
                close(app.GestureTrainerUIFigure);
                Deploy();
            else
                % Show an error message if both fields are empty
                uialert(app.GestureTrainerUIFigure, 'Please train model before deploying.', 'Error');
            end 
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            % Update the VerboseTextArea with a message
            app.TextArea.Value = {'Training started based on the configuration.'};

            % Define the base path for the configuration file
            data = SharedData.getInstance(); 
            project_dir = data.project_dir;
            projectFile = fullfile(project_dir, 'project.json');
            
            % Load the JSON configuration file
            projectInfo = jsondecode(fileread(projectFile));
            configData = projectInfo.Train.configuration;
            
            % Extract dataset directory from configuration
            datasetDir = configData.DatasetPreprocess.dataset_directory;
            numClasses = configData.DatasetPreprocess.numClasses;
            
            % Extract model type and hyperparameters from configuration
            modelConfig = configData.ModelSpecification;
            modelName = modelConfig.ModelName;
            modelArchitecture = modelConfig.ModelArchitecture;
            hyperparameters = modelConfig.Hyperparameters;
            
            % Determine the model type based on the architecture name
            switch modelArchitecture
                case "CNN"
                    modelType = 0;  % CNN Model
                case "LSTM"
                    modelType = 1;  % LSTM Model
                case "Transformer"
                    modelType = 2;  % Transformer Model
                otherwise
                    error('Unknown model architecture specified.');
            end
            
            % Extract hyperparameters for training
            Hyper_1 = hyperparameters.Hyper_1;
            Hyper_2 = hyperparameters.Hyper_2;
            Hyper_3 = hyperparameters.Hyper_3;
            Hyper_4 = hyperparameters.Hyper_4;
            Hyper_5 = hyperparameters.Hyper_5;
            Hyper_6 = hyperparameters.Hyper_6;
            
            trainModel(datasetDir, project_dir, modelName, modelType, numClasses, Hyper_1, Hyper_2, Hyper_3, Hyper_4, Hyper_5, Hyper_6, app, @(info) customOutputFcn(app, info));
            
            % Define the path for the new JSON file
            fileName = strcat('trained_', modelName, '.json');
            jsonFilePath = fullfile(project_dir, 'trained_model', fileName);
            
            % Check if the JSON file exists
            if ~isfile(jsonFilePath)
                % If the file doesn't exist, initialize the structure with configData
                newConfig = struct('configuration', configData, 'LastModifiedOn', datetime('now'));
            
                % Encode the structure into JSON format
                jsonText = jsonencode(newConfig);
            
                % Write the JSON string to the file
                fid = fopen(jsonFilePath, 'w');
                fwrite(fid, jsonText, 'char');
                fclose(fid);
                
                fprintf('Created and saved the configuration.json file.\n');
            else
                % If the file exists, update it with configData
                % Read the existing JSON file content
                fid = fopen(jsonFilePath, 'r');
                rawJsonText = fread(fid, '*char')';
                fclose(fid);
            
                % Decode the JSON text into a MATLAB structure
                existingConfig = jsondecode(rawJsonText);
            
                % Update the configuration field
                existingConfig.configuration = configData;
            
                % Update the LastModifiedOn field
                existingConfig.LastModifiedOn = datetime('now');
            
                % Encode the updated structure back into JSON
                jsonText = jsonencode(existingConfig);
            
                % Write the modified JSON back to the file
                fid = fopen(jsonFilePath, 'w');
                fwrite(fid, jsonText, 'char');
                fclose(fid);
                
                fprintf('Updated the configuration.json file.\n');
            end

            % Read the existing JSON file content
            fid = fopen(projectFile, 'r'); 
            rawJsonText = fread(fid, '*char')';
            fclose(fid);
            
            % Decode the JSON text into a MATLAB structure
            projectInfo = jsondecode(rawJsonText);
            
            % Ensure that Deploy and trained_model are correctly initialized
            if ~isfield(projectInfo, 'Deploy') || ~iscell(projectInfo.Deploy.trained_model)
                % Initialize Deploy and trained_model as a cell array if it doesn't exist
                projectInfo.Deploy.trained_model = {};
            end
  
            % Check if the new dataset name already exists in the trained_model list
            if ~ismember(modelName, projectInfo.Deploy.trained_model)
                % If it doesn't exist, add it to the list
                projectInfo.Deploy.trained_model{end+1} = modelName;
            end
            
            % Update the last modified timestamp
            projectInfo.LastModifiedOn = datetime('now');
            
            % Encode the modified project information as JSON
            jsonText = jsonencode(projectInfo);
            
            % Write the modified JSON back to the file
            fid = fopen(projectFile, 'w');  % Reopen in write mode to save the changes
            fwrite(fid, jsonText, 'char');
            fclose(fid);
            
            fprintf('Modified project JSON file\n');
            
            app.TextArea.Value = [app.TextArea.Value; 'Training finished.'];
        end

        % Button pushed function: AbandonButton
        function AbandonButtonPushed(app, event)
            % Define the base path for the configuration file
            data = SharedData.getInstance(); 
            project_dir = data.project_dir;
            projectFile = fullfile(project_dir, 'project.json');
            
            % Load the JSON configuration file
            projectInfo = jsondecode(fileread(projectFile));
            configData = projectInfo.Train.configuration;
            
            % Extract model type and hyperparameters from configuration
            modelConfig = configData.ModelSpecification;
            modelName = modelConfig.ModelName;
            fileName = strcat('trained_', modelName, '.mat');
            jsonName = strcat('trained_', modelName, '.json');
            trainedModel_dir = fullfile(project_dir, "trained_model", fileName);
            trainedJson_dir = fullfile(project_dir, "trained_model", jsonName);
        
            % Check if the model file exists
            if exist(trainedModel_dir, 'file')
                % Show confirmation dialog
                choice = uiconfirm(app.GestureTrainerUIFigure, 'Are you sure you want to delete the model?', ...
                                   'Confirm Deletion', ...
                                   'Options', {'Yes', 'No'}, ...
                                   'DefaultOption', 2, ...
                                   'Icon', 'warning');
        
                % Check the user's choice
                if strcmp(choice, 'Yes')
                    % Delete the model file
                    if exist(trainedModel_dir, 'file') == 2  % Check if the file exists
                        delete(trainedModel_dir);  % Delete the model file
                    else
                        uialert(app.GestureTrainerUIFigure, 'Model file does not exist.', 'File Error');
                        return;
                    end

                    % Delete the json file
                    if exist(trainedJson_dir, 'file') == 2  % Check if the file exists
                        delete(trainedJson_dir);  % Delete the json file
                    else
                        uialert(app.GestureTrainerUIFigure, 'Json file does not exist.', 'File Error');
                        return;
                    end
        
                    % Remove modelName from projectInfo.Deploy.trained_model
                    if isfield(projectInfo, 'Deploy') && iscell(projectInfo.Deploy.trained_model)
                        % Find the index of the modelName and remove it
                        idx = find(strcmp(projectInfo.Deploy.trained_model, modelName), 1);
                        if ~isempty(idx)
                            projectInfo.Deploy.trained_model(idx) = [];  % Correctly remove the modelName from the cell array
                        else
                            uialert(app.GestureTrainerUIFigure, 'Model name not found in project file.', 'Error');
                        end
                    else
                        uialert(app.GestureTrainerUIFigure, 'No trained models found in the project file.', 'Error');
                    end
        
                    % Update the JSON file with the modified structure
                    try
                        % Encode the modified projectInfo structure back to JSON
                        jsonText = jsonencode(projectInfo);
        
                        % Write the modified JSON back to the file
                        fid = fopen(projectFile, 'w');
                        fwrite(fid, jsonText, 'char');
                        fclose(fid);
        
                        % Update the status message
                        app.TextArea.Value = [app.TextArea.Value; 'Model abandoned and deleted. Project file updated.'];
        
                    catch ME
                        % If there is an error while writing the file, display an error
                        uialert(app.GestureTrainerUIFigure, sprintf('Error writing project file: %s', ME.message), 'Error');
                    end
        
                else
                    % If the user cancels, add a cancellation message to the VerboseTextArea
                    app.TextArea.Value = [app.TextArea.Value; 'Model deletion canceled.'];
                end
            else
                % If no model file is found, display an appropriate message
                app.TextArea.Value = [app.TextArea.Value; 'No model found to delete.'];
            end
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            try
                % Get shared data and project directory
                data = SharedData.getInstance(); 
                project_dir = data.project_dir;
                dataset_name = app.EditField.Value;
                dataset_dir = fullfile(project_dir, "dataset", dataset_name, "dataset_customisation.json");
        
                % Check if the file exists
                if isfile(dataset_dir)
                    % Read the existing JSON file content
                    fid = fopen(dataset_dir, 'r');
                    rawJsonText = fread(fid, '*char')';
                    fclose(fid);
                    
                    % Decode the JSON text into a MATLAB structure
                    jsonData = jsondecode(rawJsonText);
        
                    % Convert the structure to a readable string format for display
                    jsonStr = jsonencode(jsonData, 'PrettyPrint', true);
        
                    % Display the content in a pop-up window (using a non-blocking dialog)
                    msgbox(jsonStr, 'Dataset Preprocessing Information', 'modal');
                else
                    % Show an error dialog if the file does not exist
                    uialert(app.GestureTrainerUIFigure, sprintf('Dataset Preprocessing file not found:\n%s', dataset_dir), 'File Not Found');
                end
            catch ME
                % Handle any errors that occur during file read or decoding
                uialert(app.GestureTrainerUIFigure, sprintf('Error reading the JSON file: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: ViewButton_2
        function ViewButton_2Pushed(app, event)
            try
                % Get shared data and project directory
                data = SharedData.getInstance(); 
                project_dir = data.project_dir;
                model_name = app.EditField_2.Value;
                model_file = strcat("model_", model_name, ".json");
                model_dir = fullfile(project_dir, "model", model_file);
        
                % Check if the file exists
                if isfile(model_dir)
                    % Read the existing JSON file content
                    fid = fopen(model_dir, 'r');
                    rawJsonText = fread(fid, '*char')';
                    fclose(fid);
                    
                    % Decode the JSON text into a MATLAB structure
                    jsonData = jsondecode(rawJsonText);
        
                    % Convert the structure to a readable string format for display
                    jsonStr = jsonencode(jsonData, 'PrettyPrint', true);
        
                    % Display the content in a pop-up window
                    msgbox(jsonStr, 'Model Specification Information', 'modal');
                else
                    % Show an error dialog if the file does not exist
                    uialert(app.GestureTrainerUIFigure, sprintf('Model Specification file not found:\n%s', dataset_dir), 'File Not Found');
                end
            catch ME
                % Handle any errors that occur during file read or decoding
                uialert(app.GestureTrainerUIFigure, sprintf('Error reading the JSON file: %s', ME.message), 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GestureTrainerUIFigure = uifigure('Visible', 'off');
            app.GestureTrainerUIFigure.Name = 'GestureTrainer';
            app.PretrainPanel = uipanel(app.GestureTrainerUIFigure);
            app.PretrainPanel.Title = 'Pre-train';
            app.PreprocesseddatasetLabel = uilabel(app.PretrainPanel);
            app.PreprocesseddatasetLabel.Text = 'Preprocessed dataset: ';
            app.SpecifiedmodelLabel = uilabel(app.PretrainPanel);
            app.SpecifiedmodelLabel.Text = 'Specified model: ';
            app.EditField = uieditfield(app.PretrainPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.PretrainPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.ViewButton = uibutton(app.PretrainPanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.ViewButton_2 = uibutton(app.PretrainPanel, 'push');
            app.ViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewButton_2Pushed, true);
            app.ViewButton_2.Text = 'View';
            app.TrainPanel = uipanel(app.GestureTrainerUIFigure);
            app.TrainPanel.Title = 'Train';
            app.ActionLabel = uilabel(app.TrainPanel);
            app.ActionLabel.Text = 'Action:';
            app.TrainButton = uibutton(app.TrainPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.AbandonButton = uibutton(app.TrainPanel, 'push');
            app.AbandonButton.ButtonPushedFcn = createCallbackFcn(app, @AbandonButtonPushed, true);
            app.AbandonButton.Text = 'Abandon';
            app.TextArea = uitextarea(app.TrainPanel);
            app.TextArea.Editable = 'off';
            app.VerboseLabel = uilabel(app.TrainPanel);
            app.VerboseLabel.Text = 'Verbose:';
            app.DeployButton = uibutton(app.GestureTrainerUIFigure, 'push');
            app.DeployButton.ButtonPushedFcn = createCallbackFcn(app, @DeployButtonPushed, true);
            app.DeployButton.Icon = fullfile(pathToMLAPP, 'MR deploy.png');
            app.DeployButton.Text = 'Deploy';
            app.OverviewButton = uibutton(app.GestureTrainerUIFigure, 'push');
            app.OverviewButton.ButtonPushedFcn = createCallbackFcn(app, @OverviewButtonPushed, true);
            app.OverviewButton.Icon = fullfile(pathToMLAPP, 'Overview.png');
            app.OverviewButton.Text = 'Overview';
            app.TrainLabel = uilabel(app.GestureTrainerUIFigure);
            app.TrainLabel.Text = 'Train';
            % Show the figure after all components are created
            app.GestureTrainerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Train

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GestureTrainerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GestureTrainerUIFigure)
        end
    end
end